classdef AeolisSandFenceInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        DrawGridExtentButton           matlab.ui.control.Button
        GriddxmEditFieldLabel          matlab.ui.control.Label
        GriddxmEditField               matlab.ui.control.NumericEditField
        AddFenceLocationsButton        matlab.ui.control.Button
        ScenarioAttributesLabel        matlab.ui.control.Label
        DurationdaysEditFieldLabel     matlab.ui.control.Label
        DurationdaysEditField          matlab.ui.control.NumericEditField
        MaxWindSpeedmsEditFieldLabel   matlab.ui.control.Label
        MaxWindSpeedmsEditField        matlab.ui.control.NumericEditField
        MeanWindDirectionoLabel        matlab.ui.control.Label
        MeanWindDirectionoEditField    matlab.ui.control.NumericEditField
        WindDirectionSpreadoEditFieldLabel  matlab.ui.control.Label
        WindDirectionSpreadoEditField  matlab.ui.control.NumericEditField
        MinWindSpeedmsEditFieldLabel   matlab.ui.control.Label
        MinWindSpeedmsEditField        matlab.ui.control.NumericEditField
        TimeStepsecsEditFieldLabel     matlab.ui.control.Label
        TimeStepsecsEditField          matlab.ui.control.NumericEditField
        ModelSetupLabel                matlab.ui.control.Label
        RunModelLabel                  matlab.ui.control.Label
        RunModelButton                 matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        gridobj  % object to create grid 
        fenceobj % object to create fences
        scenario % store all data that goes to Aeolis
        DialogApp % Description
    end

    methods (Access = private)
    
        function results = func(app)
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Add Map to Plot
            file = 'bensonbeach_map_close.tif';
            A = imread(file);
            A = squeeze(A(:,:,1:3));            
            imshow(A,'Parent',app.UIAxes);
          
        end

        % Button pushed function: DrawGridExtentButton
        function DrawGridExtentButtonPushed(app, event)
            h = drawrectangle(app.UIAxes);
            app.gridobj = h;
            h.InteractionsAllowed = 'none';
        end

        % Button pushed function: AddFenceLocationsButton
        function AddFenceLocationsButtonPushed(app, event)
            %app.AddFenceLocationsButton.Enable = 'off';

            %Draw Line for fence
            lineobj = drawline(app.UIAxes);

            %Determine Fence Number
            try 
                app.fenceobj.fencenums;
                app.fenceobj.fencenums = app.fenceobj.fencenums+1;
            catch err
                app.fenceobj.fencenums = 1;    
            end
            
            app.fenceobj.fenceheights(app.fenceobj.fencenums) = 1;
            app.fenceobj.fencedepth(app.fenceobj.fencenums) = -0.5;
            
            %app.DialogApp = FenceHeightDialogBox;

            %Save Fence Data
            eval(['app.fenceobj.lineobj', num2str(app.fenceobj.fencenums),' = lineobj;'])
            %eval(['app.fenceobj.fenceheight', num2str(app.fenceobj.fencenums),' = app.DialogApp.FenceHeight;'])
            %eval(['app.fenceobj.fencedepth', num2str(app.fenceobj.fencenums),' = app.DialogApp.FenceDepth;'])
            lineobj.InteractionsAllowed = 'none';


        end

        % Button pushed function: RunModelButton
        function RunModelButtonPushed(app, event)
            
            %Generate Wind Data  
       
                %Temporarily Store Input Values
                dt = app.TimeStepsecsEditField.Value;
                day_end = app.DurationdaysEditField.Value;
                max_ws = app.MaxWindSpeedmsEditField.Value;
                min_ws = app.MinWindSpeedmsEditField.Value;
                mean_wd = app.MeanWindDirectionoEditField.Value;
                spread_wd = app.WindDirectionSpreadoEditField.Value;            
                
                %Generate Time Series
                times = 0:dt:(day_end*24*60*60);
                ws = [(max_ws-min_ws)*rand(size(times))]+min_ws;
                dirs = wrapTo180([[spread_wd*rand(size(times))]-spread_wd/2]+mean_wd);
               
                %Store Wind Time Series             
                matrix = [times(:) ws(:) dirs(:)];
                app.scenario.winds.windmatrix = matrix;
                dlmwrite('aeolis_winds.txt', matrix, ' ');

            %Generate Grids
            
                %Create Grid
                dummyz = 0; %temporary fixed value for elevation
                minx = app.gridobj.Position(1);
                maxx = app.gridobj.Position(1)+app.gridobj.Position(3);
                miny = app.gridobj.Position(2);
                maxy = app.gridobj.Position(2)+app.gridobj.Position(4);
                dx = app.GriddxmEditField.Value;
                xs = floor(minx):dx:ceil(maxx);
                ys = floor(miny):dx:ceil(maxy);
                [YS, XS] = meshgrid(ys, xs);
                ZS = ones(size(XS))*dummyz;
                
                %Store Elevation/Model Grid
                app.scenario.grids.x = xs;
                app.scenario.grids.y = ys;
                app.scenario.grids.XS = XS;
                app.scenario.grids.YS = YS;
                app.scenario.grids.ZS = ZS;
                dlmwrite('x.grd', XS, ' ');
                dlmwrite('y.grd', YS, ' ');
                dlmwrite('z.grd', ZS, ' ');
                
                %Store Fence Grids
                clear fence
                for ifence = 1:app.fenceobj.fencenums
                    fence(ifence).Geometry = 'PolyLine';
                    fence(ifence).FenceNum = ifence;
                    %eval(['fence(ifence).FenceHeight = app.fenceobj.fenceheight', num2str(ifence), ';']);
                    %eval(['fence(ifence).FenceDepth = app.fenceobj.fencedepth', num2str(ifence), ';']);
                    
                    fence(ifence).FenceHeight = app.fenceobj.fenceheights(ifence);
                    fence(ifence).FenceDepth = app.fenceobj.fencedepth(ifence);
                                        
                    
                    eval(['fence(ifence).X = app.fenceobj.lineobj', num2str(ifence),'.Position(:, 1);']);
                    eval(['fence(ifence).Y = app.fenceobj.lineobj', num2str(ifence), '.Position(:, 2);']);
                    
                    display(fence(ifence).FenceHeight)
                    display(fence(ifence).FenceDepth)
                    display(ifence)
                    display(app.fenceobj.lineobj1)
                    display(fence(1).X)
                end
                shapewrite(fence, 'fences.shp');
                
            %Generate Aeolis Params File    
                fid = fopen('aeolis.txt', 'w');
                fprintf(fid, '%s\n', ['xgrid_file = x.grd']);
                fprintf(fid, '%s\n', ['ygrid_file = y.grd']);
                fprintf(fid, '%s\n', ['bed_file = z.grd']);
                fprintf(fid, '%s\n', ['bi = 1']);
                fprintf(fid, '%s\n', ['dt = ', num2str(app.TimeStepsecsEditField.Value)]);
                fprintf(fid, '%s\n', ['dx = ', num2str(app.GriddxmEditField.Value)]);
                fprintf(fid, '%s\n', ['dy = ', num2str(app.GriddxmEditField.Value)]);
                fprintf(fid, '%s\n', ['nfractions = 1']);
                fprintf(fid, '%s\n', ['nlayers = 3']);
                fprintf(fid, '%s\n', ['nx = ', num2str(numel(xs)-1)]);
                fprintf(fid, '%s\n', ['ny = ', num2str(numel(ys)-1)]);
                fprintf(fid, '%s\n', ['output_file = aeolis.nc']);
                fprintf(fid, '%s\n', ['output_times = ', num2str(app.TimeStepsecsEditField.Value)]);
                fprintf(fid, '%s\n', ['output_types = avg']);
                %fprintf(fid, '%s\n', ['output_vars = zb zs Ct Cu uw udir uth mass pickup w']);
                fprintf(fid, '%s\n', ['output_vars = zb zs Ct Cu tau']);
                fprintf(fid, '%s\n', ['scheme = euler_backward']);
                fprintf(fid, '%s\n', ['tstart = 0']);
                fprintf(fid, '%s\n', ['tstop = ', num2str(app.DurationdaysEditField.Value*24*60*60)]);
                fprintf(fid, '%s\n', ['wind_file = aeolis_winds.txt'])
                fprintf(fid, '%s\n', ['fences = 1'])
                fclose(fid); 
                                    
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.DrawGridExtentButton = uibutton(app.UIFigure, 'push');
            app.DrawGridExtentButton.ButtonPushedFcn = createCallbackFcn(app, @DrawGridExtentButtonPushed, true);
            app.DrawGridExtentButton.Text = 'Draw Grid Extent';
            app.GriddxmEditFieldLabel = uilabel(app.UIFigure);
            app.GriddxmEditFieldLabel.Text = 'Grid dx (m)';
            app.GriddxmEditField = uieditfield(app.UIFigure, 'numeric');
            app.GriddxmEditField.Limits = [0.1 100];
            app.GriddxmEditField.Value = 1;
            app.AddFenceLocationsButton = uibutton(app.UIFigure, 'push');
            app.AddFenceLocationsButton.ButtonPushedFcn = createCallbackFcn(app, @AddFenceLocationsButtonPushed, true);
            app.AddFenceLocationsButton.Text = 'Add Fence Locations';
            app.ScenarioAttributesLabel = uilabel(app.UIFigure);
            app.ScenarioAttributesLabel.Text = 'Scenario Attributes';
            app.DurationdaysEditFieldLabel = uilabel(app.UIFigure);
            app.DurationdaysEditFieldLabel.Text = 'Duration (days)';
            app.DurationdaysEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationdaysEditField.Limits = [0.01 Inf];
            app.DurationdaysEditField.Value = 100;
            app.MaxWindSpeedmsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxWindSpeedmsEditFieldLabel.Text = 'Max Wind Speed (m/s)';
            app.MaxWindSpeedmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxWindSpeedmsEditField.Limits = [0 100];
            app.MaxWindSpeedmsEditField.Value = 20;
            app.MeanWindDirectionoLabel = uilabel(app.UIFigure);
            app.MeanWindDirectionoLabel.Text = 'Mean Wind Direction (o)';
            app.MeanWindDirectionoEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindDirectionSpreadoEditFieldLabel = uilabel(app.UIFigure);
            app.WindDirectionSpreadoEditFieldLabel.Text = 'Wind Direction Spread (o)';
            app.WindDirectionSpreadoEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindDirectionSpreadoEditField.Limits = [0 180];
            app.WindDirectionSpreadoEditField.Value = 20;
            app.MinWindSpeedmsEditFieldLabel = uilabel(app.UIFigure);
            app.MinWindSpeedmsEditFieldLabel.Text = 'Min Wind Speed (m/s)';
            app.MinWindSpeedmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinWindSpeedmsEditField.Limits = [0 100];
            app.MinWindSpeedmsEditField.Value = 4;
            app.TimeStepsecsEditFieldLabel = uilabel(app.UIFigure);
            app.TimeStepsecsEditFieldLabel.Text = 'Time Step (secs)';
            app.TimeStepsecsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeStepsecsEditField.Limits = [0.1 86400];
            app.TimeStepsecsEditField.Value = 3600;
            app.ModelSetupLabel = uilabel(app.UIFigure);
            app.ModelSetupLabel.Text = 'Model Setup';
            app.RunModelLabel = uilabel(app.UIFigure);
            app.RunModelLabel.Text = 'Run Model';
            app.RunModelButton = uibutton(app.UIFigure, 'push');
            app.RunModelButton.ButtonPushedFcn = createCallbackFcn(app, @RunModelButtonPushed, true);
            app.RunModelButton.Text = 'Run Model';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'cirp-logo.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'erdc-logo.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AeolisSandFenceInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end